classdef Trajectory_Particle_EB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EndingLimitofAxesEditField    matlab.ui.control.NumericEditField
        EndingLimitofAxesEditFieldLabel  matlab.ui.control.Label
        StartingLimitofAxesEditField  matlab.ui.control.NumericEditField
        StartingLimitofAxesEditFieldLabel  matlab.ui.control.Label
        ELECTRICFIELDPanel            matlab.ui.container.Panel
        EzEditField                   matlab.ui.control.NumericEditField
        EzEditField_6Label            matlab.ui.control.Label
        EyEditField                   matlab.ui.control.NumericEditField
        EyEditField_6Label            matlab.ui.control.Label
        ExEditField                   matlab.ui.control.NumericEditField
        ExEditField_6Label            matlab.ui.control.Label
        MAGNETICFIELDPanel            matlab.ui.container.Panel
        BzEditField                   matlab.ui.control.NumericEditField
        BzEditFieldLabel              matlab.ui.control.Label
        ByEditField                   matlab.ui.control.NumericEditField
        ByEditFieldLabel              matlab.ui.control.Label
        BxEditField                   matlab.ui.control.NumericEditField
        BxEditFieldLabel              matlab.ui.control.Label
        INITIALVELOCITYPanel          matlab.ui.container.Panel
        VzEditField                   matlab.ui.control.NumericEditField
        VzEditFieldLabel              matlab.ui.control.Label
        VyEditField                   matlab.ui.control.NumericEditField
        VyEditFieldLabel              matlab.ui.control.Label
        VxEditField                   matlab.ui.control.NumericEditField
        VxEditFieldLabel              matlab.ui.control.Label
        INITIALPOSITIONPanel          matlab.ui.container.Panel
        zEditField                    matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        ChargeEditField               matlab.ui.control.NumericEditField
        ChargeEditFieldLabel          matlab.ui.control.Label
        MassEditField                 matlab.ui.control.NumericEditField
        MassEditFieldLabel            matlab.ui.control.Label
        ClearFigureButton             matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        TRAJECTORYOFACHARGEDPARTICLEINAELECTRICANDMAGNETICFIELDLabel  matlab.ui.control.Label
        INPUTSECTIONLabel             matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function StopButtonPushed(app, event)

        end

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
            v0=[app.VxEditField.Value, app.VyEditField.Value, app.VzEditField.Value]'; %Initial Velocity
            B=[app.BxEditField.Value, app.ByEditField.Value, app.BzEditField.Value]';  %B
            E=[app.ExEditField.Value, app.EyEditField.Value, app.EzEditField.Value]'; %E
            m= app.MassEditField.Value;
            q= app.ChargeEditField.Value;
            r0=[app.xEditField.Value, app.yEditField.Value, app.zEditField.Value]';%Initial Position
            tspan=[0 70];

            % Initial Velocity
            Vx(1)=app.VxEditField.Value;
            Vy(1)=app.VyEditField.Value;
            Vz(1)=app.VzEditField.Value;

             %initial position
            x(1) = app.xEditField.Value;
            y(1) = app.yEditField.Value;
            z(1) = app.zEditField.Value;

            % Magnetic Field
            Bx=app.BxEditField.Value;
            By=app.ByEditField.Value;
            Bz=app.BzEditField.Value;


            % Electric Field
            Ex=app.ExEditField.Value;
            Ey=app.EyEditField.Value;
            Ez=app.EzEditField.Value;

            
            %to show B's direction
            St_L= app.StartingLimitofAxesEditField.Value;  
            Ed_L= app.EndingLimitofAxesEditField.Value;     % Axes Limit
            lim=2000;

            [x_q,y_q] = meshgrid(St_L:12:Ed_L,St_L:12:Ed_L);
            z_q=10*ones(size(x_q));

            u_q=B(1)*ones(size(x_q));
            v_q=B(2)*ones(size(x_q));
            w_q=B(3)*ones(size(x_q));

            
            quiver3(app.UIAxes, x_q,y_q,z_q,u_q,v_q,w_q);
            hold(app.UIAxes,'on');
            % TO show electric field lines
            z_q=-10*ones(size(x_q));

            u_q=E(1)*ones(size(x_q));
            v_q=E(2)*ones(size(x_q));
            w_q=E(3)*ones(size(x_q));
            quiver3(app.UIAxes, x_q,y_q,z_q,u_q,v_q,w_q);

            legend(app.UIAxes, 'B','E');

            % To draw axis and all
            a_a = St_L:0.1:Ed_L;
            z_a = zeros(size(a_a));
            plot3(app.UIAxes, a_a,z_a,z_a,'k','LineWidth',2);
            plot3(app.UIAxes, z_a,a_a,z_a,'k','LineWidth',2);
            plot3(app.UIAxes, z_a,z_a,a_a,'k','LineWidth',2);

            % To set axis limits
            xlim(app.UIAxes, [St_L Ed_L]);
            ylim(app.UIAxes, [St_L Ed_L]);
            zlim(app.UIAxes, [St_L Ed_L]);

            rotate3d(app.UIAxes,'on');

            plot3(app.UIAxes, r0(1),r0(2),r0(3),'*m','MarkerSize',9,'LineWidth',2);                                                                                     y0 = [r0; v0];
            
            f = @(t,y) [y(4:6); (q/m)*cross(y(4:6),B)+E];                                                                                                           
                                                                                                                                                                        [t,y] = ode23t(f,tspan,y0);

            if lim==2
            dt=0.01;
            t=o:dt:70;
            for i=2:length(t);
                x(i) = x(i-1) + Vx(i-1)*dt;
                y(i) = y(i-1) + Vy(i-1)*dt;
                z(i) = z(i-1) + Vz(i-1)*dt;
            
                Vx(i) = Vx(i-1) + (q*Ex(i-1)/m)*dt + (q/m)*(Vy(i-1)*Bz(i-1)-Vz(i-1)*By(i-1))*dt;
                Vy(i) = Vy(i-1) + (q*Ey(i-1)/m)*dt + (q/m)*(Vz(i-1)*Bx(i-1)-Vx(i-1)*Bz(i-1))*dt;
                Vz(i) = Vz(i-1) + (q*Ez(i-1)/m)*dt + (q/m)*(Vx(i-1)*By(i-1)-Vy(i-1)*Bx(i-1))*dt;
            end
            end


            %To show animation
            for n=1:length(y)
                 plot3(app.UIAxes, y(n,1),y(n,2),y(n,3),'--.r');
                 pause(0.00001);

                 if n==200
                     break;
                 end
            end
            hold(app.UIAxes, 'off');
           
        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory Of Charged Particle')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.INPUTSECTIONLabel = uilabel(app.UIFigure);
            app.INPUTSECTIONLabel.Text = 'INPUT SECTION';
            app.TRAJECTORYOFACHARGEDPARTICLEINAELECTRICANDMAGNETICFIELDLabel = uilabel(app.UIFigure);
            app.TRAJECTORYOFACHARGEDPARTICLEINAELECTRICANDMAGNETICFIELDLabel.Text = 'TRAJECTORY OF A CHARGED PARTICLE IN A ELECTRIC AND MAGNETIC FIELD';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = 'Start';
            app.ClearFigureButton = uibutton(app.UIFigure, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.Value = 5;
            app.ChargeEditFieldLabel = uilabel(app.UIFigure);
            app.ChargeEditFieldLabel.Text = 'Charge';
            app.ChargeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChargeEditField.Value = 1;
            app.INITIALPOSITIONPanel = uipanel(app.UIFigure);
            app.INITIALPOSITIONPanel.Title = 'INITIAL POSITION';
            app.xEditFieldLabel = uilabel(app.INITIALPOSITIONPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.INITIALPOSITIONPanel, 'numeric');
            app.xEditField.Value = -10;
            app.yEditFieldLabel = uilabel(app.INITIALPOSITIONPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.INITIALPOSITIONPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.INITIALPOSITIONPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.INITIALPOSITIONPanel, 'numeric');
            app.INITIALVELOCITYPanel = uipanel(app.UIFigure);
            app.INITIALVELOCITYPanel.Title = 'INITIAL VELOCITY';
            app.VxEditFieldLabel = uilabel(app.INITIALVELOCITYPanel);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.INITIALVELOCITYPanel, 'numeric');
            app.VyEditFieldLabel = uilabel(app.INITIALVELOCITYPanel);
            app.VyEditFieldLabel.Text = 'Vy';
            app.VyEditField = uieditfield(app.INITIALVELOCITYPanel, 'numeric');
            app.VzEditFieldLabel = uilabel(app.INITIALVELOCITYPanel);
            app.VzEditFieldLabel.Text = 'Vz';
            app.VzEditField = uieditfield(app.INITIALVELOCITYPanel, 'numeric');
            app.MAGNETICFIELDPanel = uipanel(app.UIFigure);
            app.MAGNETICFIELDPanel.Title = 'MAGNETIC FIELD';
            app.BxEditFieldLabel = uilabel(app.MAGNETICFIELDPanel);
            app.BxEditFieldLabel.Text = 'Bx';
            app.BxEditField = uieditfield(app.MAGNETICFIELDPanel, 'numeric');
            app.ByEditFieldLabel = uilabel(app.MAGNETICFIELDPanel);
            app.ByEditFieldLabel.Text = 'By';
            app.ByEditField = uieditfield(app.MAGNETICFIELDPanel, 'numeric');
            app.ByEditField.Value = -6;
            app.BzEditFieldLabel = uilabel(app.MAGNETICFIELDPanel);
            app.BzEditFieldLabel.Text = 'Bz';
            app.BzEditField = uieditfield(app.MAGNETICFIELDPanel, 'numeric');
            app.ELECTRICFIELDPanel = uipanel(app.UIFigure);
            app.ELECTRICFIELDPanel.Title = 'ELECTRIC FIELD';
            app.ExEditField_6Label = uilabel(app.ELECTRICFIELDPanel);
            app.ExEditField_6Label.Text = 'Ex';
            app.ExEditField = uieditfield(app.ELECTRICFIELDPanel, 'numeric');
            app.EyEditField_6Label = uilabel(app.ELECTRICFIELDPanel);
            app.EyEditField_6Label.Text = 'Ey';
            app.EyEditField = uieditfield(app.ELECTRICFIELDPanel, 'numeric');
            app.EzEditField_6Label = uilabel(app.ELECTRICFIELDPanel);
            app.EzEditField_6Label.Text = 'Ez';
            app.EzEditField = uieditfield(app.ELECTRICFIELDPanel, 'numeric');
            app.EzEditField.Value = 2;
            app.StartingLimitofAxesEditFieldLabel = uilabel(app.UIFigure);
            app.StartingLimitofAxesEditFieldLabel.Text = 'Starting Limit of Axes';
            app.StartingLimitofAxesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingLimitofAxesEditField.Value = -15;
            app.EndingLimitofAxesEditFieldLabel = uilabel(app.UIFigure);
            app.EndingLimitofAxesEditFieldLabel.Text = 'Ending Limit of Axes';
            app.EndingLimitofAxesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndingLimitofAxesEditField.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trajectory_Particle_EB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end